classdef PredictFutureCases < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        MakepredictionPanel          matlab.ui.container.Panel
        EnterlocationPanel           matlab.ui.container.Panel
        CountyEditFieldLabel         matlab.ui.control.Label
        CountyEditField              matlab.ui.control.EditField
        StateEditFieldLabel          matlab.ui.control.Label
        StateEditField               matlab.ui.control.EditField
        PredictButton                matlab.ui.control.Button
        ResetlocationButton          matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        UpdatemaskusageratingsPanel  matlab.ui.container.Panel
        GridLayout                   matlab.ui.container.GridLayout
        NeverSliderLabel             matlab.ui.control.Label
        NeverSlider                  matlab.ui.control.Slider
        RarelySliderLabel            matlab.ui.control.Label
        RarelySlider                 matlab.ui.control.Slider
        SometimesSliderLabel         matlab.ui.control.Label
        SometimesSlider              matlab.ui.control.Slider
        FrequentlySliderLabel        matlab.ui.control.Label
        FrequentlySlider             matlab.ui.control.Slider
        AlwaysSliderLabel            matlab.ui.control.Label
        AlwaysSlider                 matlab.ui.control.Slider
        PredictCovid19CasesintheUnitedStatesLabel  matlab.ui.control.Label
        MaskUsageRatingsLabel        matlab.ui.control.Label
        UIAxes2                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mlmodel
        county string
        state string = ""
        
        numDaysToPredict double = 30
        
        % ratings
        newmaskratings
        maskratings     % actual
        
        % for slider
        MaxSliderValue      double = 100
        TotalSliderValue    double = 100
        SliderValueTable    table
        SliderList          string = ["Always";"Frequently";"Sometimes";"Rarely";"Never"];
        
        allCountyData % Description
        us % us data overall
        countyMasks
        mycty
        preddata
        statelist % Description
        msklbl              string = ["NEVER","RARELY","SOMETIMES","FREQUENTLY","ALWAYS"];
        % Description
    end
    
    methods (Access = private)
        
        function results = predictThisCounty(app)
            % call function already created and reset plots, etc
            
            % TODO: reset plots
            [predcases,app.preddata] = predictCases(app.mlmodel,app.mycty,...
                30);
            
            plotPredictions(app);


        end
        
        function results = plotPredictions(app)
            % Add predictions to the plot
            
            % reset
            %             app.UIAxes.


            % get dates for pred
            % data = app.mycty;
            % actual = rmmissing(data(:,["date","cases"]));
            % plot(app.UIAxes,actual.date,actual.cases)
            % hold(app.UIAxes,"on")
            plot(app.UIAxes,data.date,data.predcases,".")
            xline(app.UIAxes,datetime("now"),"--","Today","LabelVerticalAlignment","bottom")
            legend(app.UIAxes,"Actual","Predicted","Today","Location","northwest")
            hold(app.UIAxes,"off")
        end
        
        function results = plotCountyCases(app)
            % reset
            
            plot(app.UIAxes,app.mycty.date,app.mycty.cases)
            %             title
            ylabel(app.UIAxes,"Cases")
            xlabel(app.UIAxes,"Date")
            title(app.UIAxes,["Cases Over Time",...
                string(app.county)+", "+string(app.state)])
            
            
        end
        
        function scaleUpdateSlider(app,ratings)
            app.maskratings = ratings*100;
            %use erics
            app.NeverSlider.Value = app.maskratings(1);
            app.RarelySlider.Value = app.maskratings(2);
            app.SometimesSlider.Value = app.maskratings(3);
            app.FrequentlySlider.Value = app.maskratings(4);
            app.AlwaysSlider.Value = app.maskratings(5);
            
            % update pie chart -_- (better later)
            pie(app.UIAxes2,ratings) %,app.msklbl)
            legend(app.UIAxes2,app.msklbl,"Location","eastoutside")
%             title(app.UIAxes2,"Mask Usage Survey Values")
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % TODO: add slider callbacks
            % update mask ratings, call prediction
            % hook up datepicker
            
            % load model
            x = load("expGPR.mat");
            app.mlmodel = x.regressionGP;
            
            %             % load data
            %             y = load("usData.mat","us");
            %             app.us = y.us;
            
            z = load("allCountyData.mat","allCountyData","countyMasks");
            app.allCountyData = z.allCountyData;
            app.countyMasks = z.countyMasks;
            
            % set defaults
            
            
            % create overall plot
            %             plot(app.UIAxes,app.us.date,app.us.cases)
            ylabel(app.UIAxes,"Cases")
            xlabel(app.UIAxes,"Date")
            title(app.UIAxes,"Cases Over Time")


            %%% Add erics code for slider
            app.SliderValueTable = table;
            app.SliderValueTable.name = app.SliderList;
            app.SliderValueTable.value = zeros(numel(app.SliderList),1);
            for idx = 1:numel(app.SliderList)
                app.SliderValueTable.value(idx) = ...
                    app.(app.SliderList(idx) + "Slider").Value;
            end
            
            
            % populate state dropdown
            % Add callback for state -> populate county dropdown
%             app.statelist = unique(app.countyMasks.abbrev);
%             app.StateDropDown.Items = app.statelist;
%             
            
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            predictThisCounty(app)
            
        end

        % Value changed function: AlwaysSlider, FrequentlySlider, 
        % NeverSlider, RarelySlider, SometimesSlider
        function SliderValueChanged(app, event)
            thisSlider = event.Source.Tag;
            otherSliders = app.SliderList(~ismember(app.SliderList,thisSlider));
            for idx = 1:numel(otherSliders)
                app.SliderValueTable.value(app.SliderValueTable.name == otherSliders(idx)) = ...
                    app.(otherSliders(idx) + "Slider").Value;
            end
            app.SliderValueTable.value(app.SliderValueTable.name == thisSlider) = ...
                app.(thisSlider + "Slider").Value;
        end

        % Value changing function: AlwaysSlider, FrequentlySlider, 
        % NeverSlider, RarelySlider, SometimesSlider
        function SliderValueChanging(app, event)
            %             changingValue = event.Value;
            
            % TODO: check w/ Eric's - possibly switched callbacks for
            % changed and changing?
            
            thisSlider = event.Source.Tag;
            changingValue = event.Value;
            
            oldValue = app.(thisSlider + "Slider").Value;
            deltaValue = changingValue - oldValue;
            
            otherSliders = app.SliderList(~ismember(app.SliderList,thisSlider));
            app.TotalSliderValue = app.TotalSliderValue - ...
                app.(thisSlider + "Slider").Value + changingValue;
            remainderValue = -deltaValue;
            sliderMat = app.SliderValueTable.value(ismember(app.SliderValueTable.name,otherSliders));
            switch sum(sliderMat)
                case 0
                    ratio = 0.25 * ones(4,1);
                otherwise
                    ratio = sliderMat / sum(sliderMat);
            end
            for idx = 1:4
                app.(otherSliders(idx)+"Slider").Value = ...
                    sliderMat(idx) + ratio(idx)*remainderValue;
            end
        end

        % Value changed function: CountyEditField
        function CountyEditFieldValueChanged(app, event)
            app.county = app.CountyEditField.Value;
%             if app.state == ""
%                 app.mycty = selectCountyState(app.allCountyData,app.county);
%             else
%                 app.mycty = selectCountyState(app.allCountyData,app.county,app.state);
%             end
%             
%             % add states to dropdown
%             % check unique states
%             app.mycty.abbrev = removecats(app.mycty.abbrev);
%             states = categories(app.mycty.abbrev);
%             app.StateDropDown.Items = [states;app.statelist];
%             app.StateDropDown.Value = states;
            
%             if length(states) == 1
%                 plotCountyCases(app)
%                 scaleUpdateSlider(app,app.mycty{1,app.msklbl})
%                 %                 app.state = states;
%             end
%             
%             


        end

        % Value changed function: StateEditField
        function StateEditFieldValueChanged(app, event)
            value = app.StateEditField.Value;
%             app.state = app.StateDropDown.Value;
            
            ctydata = app.mycty;
            
%             if isempty(ctydata) || string(app.county) ~= ""
if isempty(ctydata) || string(app.county) ~= ""
                app.mycty = selectCountyState(app.allCountyData,app.county,app.state);
                plotCountyCases(app)
                scaleUpdateSlider(app,ctydata{1,app.msklbl})
            elseif length(unique(ctydata.abbrev)) > 1
                app.mycty = selectCountyState(ctydata,app.county,app.state);
                plotCountyCases(app)
                scaleUpdateSlider(app,app.mycty{1,app.msklbl})
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MakepredictionPanel = uipanel(app.UIFigure);
            app.MakepredictionPanel.Title = 'Make prediction';
            app.EnterlocationPanel = uipanel(app.MakepredictionPanel);
            app.EnterlocationPanel.Title = 'Enter location';
            app.CountyEditFieldLabel = uilabel(app.EnterlocationPanel);
            app.CountyEditFieldLabel.Text = 'County';
            app.CountyEditField = uieditfield(app.EnterlocationPanel, 'text');
            app.CountyEditField.ValueChangedFcn = createCallbackFcn(app, @CountyEditFieldValueChanged, true);
            app.StateEditFieldLabel = uilabel(app.EnterlocationPanel);
            app.StateEditFieldLabel.Text = 'State';
            app.StateEditField = uieditfield(app.EnterlocationPanel, 'text');
            app.StateEditField.ValueChangedFcn = createCallbackFcn(app, @StateEditFieldValueChanged, true);
            app.PredictButton = uibutton(app.MakepredictionPanel, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.ResetlocationButton = uibutton(app.MakepredictionPanel, 'push');
            app.ResetlocationButton.Text = 'Reset location';
            app.UIAxes = uiaxes(app.MakepredictionPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UpdatemaskusageratingsPanel = uipanel(app.UIFigure);
            app.UpdatemaskusageratingsPanel.Title = 'Update mask usage ratings';
            app.GridLayout = uigridlayout(app.UpdatemaskusageratingsPanel);
            app.NeverSliderLabel = uilabel(app.GridLayout);
            app.NeverSliderLabel.Text = 'Never';
            app.NeverSlider = uislider(app.GridLayout);
            app.NeverSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.NeverSlider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.NeverSlider.Tag = 'Never';
            app.NeverSlider.Value = 50;
            app.RarelySliderLabel = uilabel(app.GridLayout);
            app.RarelySliderLabel.Text = 'Rarely';
            app.RarelySlider = uislider(app.GridLayout);
            app.RarelySlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.RarelySlider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.RarelySlider.Tag = 'Rarely';
            app.RarelySlider.Value = 20;
            app.SometimesSliderLabel = uilabel(app.GridLayout);
            app.SometimesSliderLabel.Text = 'Sometimes';
            app.SometimesSlider = uislider(app.GridLayout);
            app.SometimesSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.SometimesSlider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.SometimesSlider.Tag = 'Sometimes';
            app.FrequentlySliderLabel = uilabel(app.GridLayout);
            app.FrequentlySliderLabel.Text = 'Frequently';
            app.FrequentlySlider = uislider(app.GridLayout);
            app.FrequentlySlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.FrequentlySlider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.FrequentlySlider.Tag = 'Frequently';
            app.AlwaysSliderLabel = uilabel(app.GridLayout);
            app.AlwaysSliderLabel.Text = 'Always';
            app.AlwaysSlider = uislider(app.GridLayout);
            app.AlwaysSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.AlwaysSlider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.AlwaysSlider.Tag = 'Always';
            app.AlwaysSlider.Value = 40;
            app.PredictCovid19CasesintheUnitedStatesLabel = uilabel(app.UIFigure);
            app.PredictCovid19CasesintheUnitedStatesLabel.Text = 'Predict Covid-19 Cases in the United States';
            app.MaskUsageRatingsLabel = uilabel(app.UIFigure);
            app.MaskUsageRatingsLabel.Text = 'Mask Usage Ratings';
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PredictFutureCases

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end