classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NYTCOVID19DataUIFigure      matlab.ui.Figure
        UIGrid                      matlab.ui.container.GridLayout
        TabGroup                    matlab.ui.container.TabGroup
        PlotTab                     matlab.ui.container.Tab
        GridLayout6                 matlab.ui.container.GridLayout
        LocationTitleLabel          matlab.ui.control.Label
        Panel_2                     matlab.ui.container.Panel
        MaskUsageGrid               matlab.ui.container.GridLayout
        MaskUsageButtonGrid         matlab.ui.container.GridLayout
        PredictButton               matlab.ui.control.Button
        SliderGrid                  matlab.ui.container.GridLayout
        Slider1Label                matlab.ui.control.Label
        Slider1                     matlab.ui.control.Slider
        Slider2Label                matlab.ui.control.Label
        Slider2                     matlab.ui.control.Slider
        Slider3Label                matlab.ui.control.Label
        Slider3                     matlab.ui.control.Slider
        Slider4Label                matlab.ui.control.Label
        Slider4                     matlab.ui.control.Slider
        Slider5                     matlab.ui.control.Slider
        Slider5Label                matlab.ui.control.Label
        Label1                      matlab.ui.control.Label
        Label2                      matlab.ui.control.Label
        Label3                      matlab.ui.control.Label
        Label4                      matlab.ui.control.Label
        Label5                      matlab.ui.control.Label
        MaskUsageDistributionAxes   matlab.ui.control.UIAxes
        Panel                       matlab.ui.container.Panel
        CasesPlotGrid               matlab.ui.container.GridLayout
        CasesByPopulationAxes       matlab.ui.control.UIAxes
        LocationTab                 matlab.ui.container.Tab
        LocationSelectionTabGrid    matlab.ui.container.GridLayout
        SelectaCountyandStatePanel  matlab.ui.container.Panel
        LocationSelectionPanelGrid  matlab.ui.container.GridLayout
        GridLayout7                 matlab.ui.container.GridLayout
        CountyEditField             matlab.ui.control.EditField
        CountyEditFieldLabel        matlab.ui.control.Label
        StateDropDown               matlab.ui.control.DropDown
        StateDropDownLabel          matlab.ui.control.Label
        CountyDropDownLabel         matlab.ui.control.Label
        CountyDropDown              matlab.ui.control.DropDown
        SelectandPlotButton         matlab.ui.control.Button
        Label                       matlab.ui.control.Label
    end

    
    properties (Access = private)
        SliderValueTable    table
        mlmodel
        allCountyData
        countyMasks
        statelist
        SliderList          string = ["Never";"Rarely";"Sometimes";"Frequently";"Always"];
        msklbl              string = ["NEVER","RARELY","SOMETIMES","FREQUENTLY","ALWAYS"];
        ColorList = [    0    0.4470    0.7410
                    0.8500    0.3250    0.0980
                    0.9290    0.6940    0.1250
                    0.4940    0.1840    0.5560
                    0.4660    0.6740    0.1880]
        county
        state
        thisCountyData
        maskratings
        preddata
        predcases
        element
        casedata % timetable w/ cases for county,state

    end
    
    methods (Access = private)
        
        function initSliders(app)
            app.SliderValueTable = table;
            app.SliderValueTable.slider = [app.Slider1; app.Slider2; app.Slider3; app.Slider4; app.Slider5];
            app.SliderValueTable.label = [app.Slider1Label; app.Slider2Label; app.Slider3Label; app.Slider4Label; app.Slider5Label];
            app.SliderValueTable.name = app.SliderList;
            app.SliderValueTable.value = zeros(numel(app.SliderList),1);
            app.SliderValueTable.valueLabel= [app.Label1; app.Label2; app.Label3; app.Label4; app.Label5];
            app.SliderValueTable.color = app.ColorList;
            for idx = 1:numel(app.SliderList)
                app.SliderValueTable.value(idx) = ...
                    app.SliderValueTable.slider(idx).Value;
                app.SliderValueTable.label(idx).Text = ...
                    app.SliderValueTable.name(idx);
                app.SliderValueTable.label(idx).FontColor = app.SliderValueTable.color(idx,:);
                app.SliderValueTable.valueLabel(idx).FontColor = app.SliderValueTable.color(idx,:);
                app.SliderValueTable.slider(idx).FontColor = app.SliderValueTable.color(idx,:);
            end                   

            app.updateSliderValueColumn();
%             app.SliderValueTable.value = app.SliderValueTable.slider.Value; % assumed that this would vectorize            
        end
        
        
        function initCasePlot(app)
            % create overall plot
            %             plot(app.MaskUsageDistributionAxes,app.us.date,app.us.cases)
            ylabel(app.CasesByPopulationAxes,"Cases")
            xlabel(app.CasesByPopulationAxes,"Date")
            title(app.CasesByPopulationAxes,"Cases Over Time")            
        end
        
        
        function initUsagePlot(app)  %#ok
            
        end
        
        
        function loadFiles(app)
            % load model
             app.mlmodel = load("expGPRnew.mat").regressionGP;
%            app.mlmodel = load("expGPRFeb18.mat").regressionGP;
            
            % load dataset
%             dataset = load("allCountyData.mat","allCountyData","countyMasks");
%             app.allCountyData = dataset.allCountyData;
%             app.countyMasks = dataset.countyMasks;
            app.countyMasks = load("countyMasks.mat").countyMasks;
            % TODO: Title Case
            % app.countyMasks.county = lower(string(app.countyMasks.county));
            
            app.CountyDropDown.Items = [""; categories(app.countyMasks.county)];
            app.CountyDropDown.Enable = true;
            app.CountyDropDownLabel.Enable = true;

            app.StateDropDown.Items = [""; categories(app.countyMasks.abbrev)];
            app.StateDropDown.Enable = true;
            app.StateDropDownLabel.Enable = true;
%             app.SelectandPlotButton.Enable = true;
                        
        end
        
        
        function updateSliderValueColumn(app)
            for idx = 1:numel(app.SliderList)
                app.SliderValueTable.value(idx) = ...
                    app.SliderValueTable.slider(idx).Value;
                app.SliderValueTable.valueLabel(idx).Text = round(app.SliderValueTable.value(idx), 2) + "%";
            end            
            app.SliderValueTable;
        end
        
                
        function plotCountyCases(app)
            % prep data
            % added casedata as a property
            
            plot(app.CasesByPopulationAxes, app.thisCountyData.dates, app.thisCountyData.cases);
%             plot(app.CasesByPopulationAxes, app.casedata.date, app.casedata.cases);
            title(app.CasesByPopulationAxes,["Cases Over Time",...
                string(app.county)+", "+string(app.state)])
            delete(app.CasesByPopulationAxes.Legend)
            reset(app.CasesByPopulationAxes)
        end
        
        
        function updateSliders(app, ratings)
            app.maskratings = ratings*100;
            app.SliderValueTable.value = app.maskratings';
            app.Slider1.Value = app.maskratings(1);
            app.Slider2.Value = app.maskratings(2);
            app.Slider3.Value = app.maskratings(3);
            app.Slider4.Value = app.maskratings(4);
            app.Slider5.Value = app.maskratings(5);
            app.updateSliderValueColumn();
            
        end
        
        
        function plotMaskUsage(app)
            % orig_maskvalues = app.maskratings;
            orig_maskvalues = app.thisCountyData{1,app.msklbl};
            % new_maskvalues = nan(size(orig_maskvalues)); % try real one?
            new_maskvalues = (app.SliderValueTable.value')/100;
            maskvals_to_plot = [orig_maskvalues; new_maskvalues];%%            
            
%             figure
           
            barh(app.MaskUsageDistributionAxes,maskvals_to_plot,'stacked')
            app.MaskUsageDistributionAxes.XTickLabel = ["Real","Sliders"];
            title(app.MaskUsageDistributionAxes,["Mask Usage Distribution",...
                string(app.county)+", "+string(app.state)])
%             app.MaskUsageDistributionAxes.XLim = [0.5 1.5];
%             legend(app.MaskUsageDistributionAxes,msklbls,"Location","southoutside",'NumColumns',5)
        end
        
        
        function plotPredictions(app)
            % get dates for pred
            %             dont need this, actual is already plotted?
            
%             data = app.preddata;
%             actual = rmmissing(data(:,["date","cases"]));
%             plot(app.CasesByPopulationAxes,actual.date,actual.cases)  %
%             plot(app.CasesByPopulationAxes,app.casedata.Time,app.casedata.cases)
            plot(app.CasesByPopulationAxes,app.thisCountyData.dates,app.thisCountyData.cases)
            hold(app.CasesByPopulationAxes,"on")
            
            plot(app.CasesByPopulationAxes,app.preddata.dates,app.preddata.predcases,".")
            % dont need this for now, not yet predicting future... will add back in later
            xline(app.CasesByPopulationAxes,datetime("now"),"--","Today","LabelVerticalAlignment","bottom")
            legend(app.CasesByPopulationAxes,"Actual","Predicted","Today","Location","northwest")
%             legend(app.CasesByPopulationAxes,"Actual","Predicted","Location","northwest")
            hold(app.CasesByPopulationAxes,"off")            
        end
        
        
        function makePredictions(app, mskratings)
            % use new function - to be updated w/ future dates
%             [app.predcases,date] = predCases(app.mlmodel,app.thisCountyData,...  
%                 mskratings);
%             app.preddata = timetable(date, app.predcases);
            app.preddata = predictCases(app.mlmodel,app.thisCountyData,...
                7,mskratings);            
        end
        
        
        function results = validateDropDowns(app)
            results = nnz(app.StateDropDown.Value == app.countyMasks.abbrev) > 0 && nnz(app.CountyDropDown.Value == app.countyMasks.county) > 0 && app.StateDropDown.Value ~= "PR";
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp("Running Startup Function")
            % TODO:
            % update mask ratings, call prediction
            % hook up datepicker
            app.TabGroup.SelectedTab = app.LocationTab;


            app.initCasePlot();
            
            app.initSliders();
            app.initUsagePlot();
            % load model
            app.loadFiles();
        end

        % Value changed function: CountyDropDown, CountyEditField
        function CountyValueChanged(app, event)
            county = event.Source.Value; %#ok
            state = app.StateDropDown.Value; %#ok
            % county = lower(string(app.CountyEditField.Value)); %Title Case

            cidx = county == app.countyMasks.county; %#ok
            
            if nnz(cidx) > 0 
                states = unique(app.countyMasks.abbrev(cidx));
                app.StateDropDown.Items = [""; states];
                if ismember(state, states) %#ok
                    % index = find(string(app.StateDropDown.Items) == state);
                    app.StateDropDown.Value = state %#ok   %app.StateDropDown.Items(index);
                else
                    if county ~= string(app.CountyDropDown.Items(1))  %#ok
                        app.StateDropDown.Value = app.StateDropDown.Items(2);
                    end
                end
                app.SelectandPlotButton.Enable = true;
            else
                app.StateDropDown.Items = [""; categories(app.countyMasks.abbrev)];            
                if county == string(app.CountyDropDown.Items(1))  %#ok
                    app.StateDropDown.Value = app.StateDropDown.Items(1);
                end
                app.SelectandPlotButton.Enable = false;
            end                                 
        end

        % Value changed function: StateDropDown
        function StateValueChanged(app, event)
            state = event.Source.Value; %#ok
            county = app.CountyDropDown.Value; %#ok

            sidx = state == app.countyMasks.abbrev; %#ok

            if nnz(sidx) > 0 
                counties = unique(app.countyMasks.county(sidx));
                app.CountyDropDown.Items = [""; counties];
                if ismember(county, counties) %#ok
                    % index = find(string(app.CountyDropDown.Items) == county);
                    app.CountyDropDown.Value = county %#ok %app.CountyDropDown.Items(index);
                else
                    if state ~= string(app.StateDropDown.Items(1)) %#ok
                        app.CountyDropDown.Value = app.CountyDropDown.Items(2);                        
                    end
                end
                app.SelectandPlotButton.Enable = true;
            else
                app.CountyDropDown.Items = [""; categories(app.countyMasks.county)];
                if state == string(app.StateDropDown.Items(1)) %#ok
                    app.CountyDropDown.Value = app.CountyDropDown.Items(1);
                end
                app.SelectandPlotButton.Enable = false;
            end

        end

        % Value changed function: Slider1, Slider2, Slider3, 
        % Slider4, Slider5
        function SliderValueChanged(app, event)
            slider = event.Source;
            diff = event.Value - event.PreviousValue;
            index = find(app.SliderValueTable{:,"slider"} == event.Source);
            nextIndex = mod(index, height(app.SliderValueTable)) + 1;
            otherSlider = app.SliderValueTable.slider(nextIndex);
            if (otherSlider.Value - diff >= 0)
                otherSlider.Value = otherSlider.Value - diff;
            else
                slider.Value = event.PreviousValue;
            end
            app.updateSliderValueColumn();
            app.plotMaskUsage()


        end

        % Button pushed function: SelectandPlotButton
        function ButtonSelectandPlotPushed(app, event)
            if ~app.validateDropDowns()
                uialert(app.NYTCOVID19DataUIFigure,...
                    "No data for selected location.",'Error',...
                    'Icon','warning');
                app.SelectandPlotButton.Enable = false;
                return
            end
            app.county = app.CountyDropDown.Value;
            app.state = app.StateDropDown.Value;
            disp("Selected " + app.county + ", " + app.state);
            app.LocationTitleLabel.Text = app.county + " County, " + app.state;
            app.TabGroup.SelectedTab = app.PlotTab;
            d = uiprogressdlg(app.NYTCOVID19DataUIFigure,... %'Title','Getting data',...
                "Message", "Getting data for " + string(app.county) + ", " + ...
                string(app.state), 'Indeterminate', 'on');

            %             app.thisCountyData = selectCountyState(app.allCountyData,app.county,app.state);
            
            % get all the data for county, state
%             idx = app.countyMasks.county == app.county & ...
%                 app.countyMasks.abbrev == app.state;
%             app.thisCountyData = app.countyMasks(idx,:);
%             cases = app.thisCountyData.cases{1}; 
%             app.casedata = timetable(cases,'TimeStep',caldays(1),...
%                 'StartTime',app.thisCountyData.min_date);
% 
            app.thisCountyData = selectCountyState(app.countyMasks,app.county,app.state);
            
%             if app.HoldPlotsCheckBox.Value == true && height(app.CasesByPopulationAxes.Children) > 0
%                 app.CasesByPopulationAxes.Children(1)
%             else
%                 app.plotCountyCases();
%             end
            app.plotCountyCases();
            app.updateSliders(app.thisCountyData{1,app.msklbl});
            app.plotMaskUsage();
            app.PredictButton.Enable = true;
            d.close()  ;          
            %disp(app.CasesByPopulationAxes.Children)

        end

        % Button pushed function: PredictButton
        function ButtonPredictPushed(app, event)
            d = uiprogressdlg(app.NYTCOVID19DataUIFigure,... %'Title','Getting data',...
                "Message","THIS. IS. MACHINE LEARNING.",'Indeterminate','on');            
            mskratings = (app.SliderValueTable.value')/100;
            app.makePredictions(mskratings)
            app.plotPredictions()
            d.close()            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NYTCOVID19DataUIFigure = uifigure('Visible', 'off');
            app.NYTCOVID19DataUIFigure.Name = 'NYT COVID-19 Data';
            app.UIGrid = uigridlayout(app.NYTCOVID19DataUIFigure);
            app.TabGroup = uitabgroup(app.UIGrid);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.GridLayout6 = uigridlayout(app.PlotTab);
            app.LocationTitleLabel = uilabel(app.GridLayout6);
            app.LocationTitleLabel.Text = 'Select a Location';
            app.Panel_2 = uipanel(app.GridLayout6);
            app.MaskUsageGrid = uigridlayout(app.Panel_2);
            app.MaskUsageButtonGrid = uigridlayout(app.MaskUsageGrid);
            app.PredictButton = uibutton(app.MaskUsageButtonGrid, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPredictPushed, true);
            app.PredictButton.Enable = 'off';
            app.PredictButton.Text = ' Predict ';
            app.SliderGrid = uigridlayout(app.MaskUsageGrid);
            app.Slider1Label = uilabel(app.SliderGrid);
            app.Slider1Label.Text = 'Slider1';
            app.Slider1 = uislider(app.SliderGrid);
            app.Slider1.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.Slider1.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider1.MinorTicks = [0 2.5 5 7.5 10 12.5 15 17.5 20 22.5 25 27.5 30 32.5 35 37.5 40 42.5 45 47.5 50 52.5 55 57.5 60 62.5 65 67.5 70 72.5 75 77.5 80 82.5 85 87.5 90 92.5 95 97.5 100];
            app.Slider1.Value = 20;
            app.Slider2Label = uilabel(app.SliderGrid);
            app.Slider2Label.Text = 'Slider2';
            app.Slider2 = uislider(app.SliderGrid);
            app.Slider2.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider2.MinorTicks = [0 2.5 5 7.5 10 12.5 15 17.5 20 22.5 25 27.5 30 32.5 35 37.5 40 42.5 45 47.5 50 52.5 55 57.5 60 62.5 65 67.5 70 72.5 75 77.5 80 82.5 85 87.5 90 92.5 95 97.5 100];
            app.Slider2.Value = 20;
            app.Slider3Label = uilabel(app.SliderGrid);
            app.Slider3Label.Text = 'Slider3';
            app.Slider3 = uislider(app.SliderGrid);
            app.Slider3.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.Slider3.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider3.MinorTicks = [0 2.5 5 7.5 10 12.5 15 17.5 20 22.5 25 27.5 30 32.5 35 37.5 40 42.5 45 47.5 50 52.5 55 57.5 60 62.5 65 67.5 70 72.5 75 77.5 80 82.5 85 87.5 90 92.5 95 97.5 100];
            app.Slider3.Value = 20;
            app.Slider4Label = uilabel(app.SliderGrid);
            app.Slider4Label.Text = 'Slider4';
            app.Slider4 = uislider(app.SliderGrid);
            app.Slider4.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.Slider4.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider4.MinorTicks = [0 2.5 5 7.5 10 12.5 15 17.5 20 22.5 25 27.5 30 32.5 35 37.5 40 42.5 45 47.5 50 52.5 55 57.5 60 62.5 65 67.5 70 72.5 75 77.5 80 82.5 85 87.5 90 92.5 95 97.5 100];
            app.Slider4.Value = 20;
            app.Slider5 = uislider(app.SliderGrid);
            app.Slider5.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.Slider5.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider5.MinorTicks = [0 2.5 5 7.5 10 12.5 15 17.5 20 22.5 25 27.5 30 32.5 35 37.5 40 42.5 45 47.5 50 52.5 55 57.5 60 62.5 65 67.5 70 72.5 75 77.5 80 82.5 85 87.5 90 92.5 95 97.5 100];
            app.Slider5.Value = 20;
            app.Slider5Label = uilabel(app.SliderGrid);
            app.Slider5Label.Text = 'Slider5';
            app.Label1 = uilabel(app.SliderGrid);
            app.Label1.Text = 'Label1';
            app.Label2 = uilabel(app.SliderGrid);
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.SliderGrid);
            app.Label3.Text = 'Label3';
            app.Label4 = uilabel(app.SliderGrid);
            app.Label4.Text = 'Label4';
            app.Label5 = uilabel(app.SliderGrid);
            app.Label5.Text = 'Label5';
            app.MaskUsageDistributionAxes = uiaxes(app.MaskUsageGrid);
            title(app.MaskUsageDistributionAxes, 'Mask Usage Distribution')
            app.Panel = uipanel(app.GridLayout6);
            app.CasesPlotGrid = uigridlayout(app.Panel);
            app.CasesByPopulationAxes = uiaxes(app.CasesPlotGrid);
            title(app.CasesByPopulationAxes, 'Cases Over Time')
            xlabel(app.CasesByPopulationAxes, 'Date')
            ylabel(app.CasesByPopulationAxes, 'Total Cases')
            app.CasesByPopulationAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.LocationTab = uitab(app.TabGroup);
            app.LocationTab.Title = 'Location';
            app.LocationSelectionTabGrid = uigridlayout(app.LocationTab);
            app.SelectaCountyandStatePanel = uipanel(app.LocationSelectionTabGrid);
            app.SelectaCountyandStatePanel.Title = 'Select a County and State';
            app.LocationSelectionPanelGrid = uigridlayout(app.SelectaCountyandStatePanel);
            app.GridLayout7 = uigridlayout(app.LocationSelectionPanelGrid);
            app.CountyEditField = uieditfield(app.GridLayout7, 'text');
            app.CountyEditField.ValueChangedFcn = createCallbackFcn(app, @CountyValueChanged, true);
            app.CountyEditField.Visible = 'off';
            app.CountyEditField.Value = 'Norfol';
            app.CountyEditFieldLabel = uilabel(app.GridLayout7);
            app.CountyEditFieldLabel.Visible = 'off';
            app.CountyEditFieldLabel.Text = 'County';
            app.StateDropDown = uidropdown(app.GridLayout7);
            app.StateDropDown.Items = {'Select a county'};
            app.StateDropDown.Editable = 'on';
            app.StateDropDown.ValueChangedFcn = createCallbackFcn(app, @StateValueChanged, true);
            app.StateDropDown.Enable = 'off';
            app.StateDropDown.Value = 'Select a county';
            app.StateDropDownLabel = uilabel(app.GridLayout7);
            app.StateDropDownLabel.Enable = 'off';
            app.StateDropDownLabel.Text = 'State';
            app.CountyDropDownLabel = uilabel(app.GridLayout7);
            app.CountyDropDownLabel.Enable = 'off';
            app.CountyDropDownLabel.Text = 'County';
            app.CountyDropDown = uidropdown(app.GridLayout7);
            app.CountyDropDown.Items = {'Select a state'};
            app.CountyDropDown.Editable = 'on';
            app.CountyDropDown.ValueChangedFcn = createCallbackFcn(app, @CountyValueChanged, true);
            app.CountyDropDown.Enable = 'off';
            app.CountyDropDown.Value = 'Select a state';
            app.SelectandPlotButton = uibutton(app.LocationSelectionPanelGrid, 'push');
            app.SelectandPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonSelectandPlotPushed, true);
            app.SelectandPlotButton.Enable = 'off';
            app.SelectandPlotButton.Tooltip = {''};
            app.SelectandPlotButton.Text = 'Select and Plot';
            app.Label = uilabel(app.UIGrid);
            app.Label.Text = 'New York Times COVID-19 Data Visualization and Prediction';
            % Show the figure after all components are created
            app.NYTCOVID19DataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NYTCOVID19DataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NYTCOVID19DataUIFigure)
        end
    end
end